classdef ManipulatorApp_Sliders < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        ForwardKinematicsLabel  matlab.ui.control.Label
        InverseKinematicsLabel  matlab.ui.control.Label
        ZCoordinateSlider       matlab.ui.control.Slider
        YCoordinateSlider       matlab.ui.control.Slider
        XCoordinateSlider       matlab.ui.control.Slider
        Link3mmSlider           matlab.ui.control.Slider
        Link2degSlider          matlab.ui.control.Slider
        Link1degSlider          matlab.ui.control.Slider
        ZCoordinateSliderLabel  matlab.ui.control.Label
        YCoordinateSliderLabel  matlab.ui.control.Label
        XCoordinateSliderLabel  matlab.ui.control.Label
        Link3mmSliderLabel      matlab.ui.control.Label
        Link2degSliderLabel     matlab.ui.control.Label
        Link1degSliderLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        ws = [-300 300 -300 300 0 200]; % Robot workspace
    end
    
    methods (Access = private)
        
        function UpdateCoords(app, T)
            % Update the X Y Z coords
            T
            app.XCoordinateSlider.Value = round(T.t(1));
            app.YCoordinateSlider.Value = round(T.t(2));
            app.ZCoordinateSlider.Value = round(T.t(3));
        end

        function Robot = initRobot(app)
            % DH Table parameters
            %       θ  d   a   α   σ
            dh = [  0  120 140 0   0   ;
                    0  50  140 pi  0   ; 
                    0  48  0   pi  1  ];

            % Generate robot
            Robot = SerialLink(dh);
            Robot.name = " ";
            Robot.links(3).qlim = [0 100];
            Robot.display
            
        end
        
        function forward(app)
            %try    
                % Acquire angles from the UI
                q1 = app.Link1degSlider.Value;
                q2 = app.Link2degSlider.Value;
                q3 = app.Link3mmSlider.Value;

                % Movements
                Q(1) = q1 * pi/180;
                Q(2) = q2 * pi/180;
                Q(3) = q3 + 48;

                % % % % % PLOTTING
                Robot = initRobot(app);
                T = Robot.fkine(Q);
                Robot.plot(Q, 'workspace', app.ws);
                Robot.jacob0(Q)

                UpdateCoords(app, T);

            % Catch exceptions
            %catch ex
                %f = msgbox(ex.message,'An Error has occured');
            %end
        end

        function inverse(app, who)
             %try
                % Read in coords from UI
                X = app.XCoordinateSlider.Value
                Y = app.YCoordinateSlider.Value
                Z = app.ZCoordinateSlider.Value
                
                % Input validation
                arm1 = 140;
                arm2 = 240;
                r = sqrt(X^2 + Y^2);
                min = sqrt(arm1^2 + arm2^2);
                max = arm1 + arm2;

                if r > max
                    if who == 'X'
                        temp = Y;
                        Y = floor(sqrt(max^2 - X^2));
                        if temp < 0
                            Y = -Y;
                        end

                    elseif who == 'Y'
                        temp = X;
                        X = floor(sqrt(max^2 - Y^2));
                        if temp < 0
                            X = -X;
                        end

                    else
                        error("But... that's not possible!?");
                    end

                elseif r < min
                    if who == 'X'
                        temp = Y;
                        Y = floor(sqrt(min^2 - X^2));
                        if temp < 0
                            Y = -Y;
                        end

                    elseif who == 'Y'
                        temp = X;
                        X = floor(sqrt(min^2 - Y^2));
                        if temp < 0
                            X = -X;
                        end

                    else
                        error("But... that's not possible!?");
                    end
                end
                

                T = transl(X, Y, Z);

                % % % % % PLOTTING
                Robot = initRobot(app);
                Q = Robot.ikine(T, [280 0 72], 'mask', [1 1 1 0 0 0])
                Robot.plot(Q, 'workspace', app.ws);

                % Update the UI
                app.Link1degSlider.Value = round(Q(1)*180/pi);
                app.Link2degSlider.Value = round(Q(2)*180/pi);
                app.Link3mmSlider.Value = round(Q(3)-48);
                UpdateCoords(app,T);
            

            %catch ex
                %f = msgbox(ex.message,'Invalid Operation');
            %end
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: XCoordinateSlider
        function XCoordinateSliderValueChanged(app, event)
            inverse(app, 'X');
        end

        % Value changed function: YCoordinateSlider
        function YCoordinateSliderValueChanged(app, event)
            inverse(app, 'Y');
        end

        % Value changed function: ZCoordinateSlider
        function ZCoordinateSliderValueChanged(app, event)
            inverse(app, 'Z');
        end

        % Value changed function: Link1degSlider
        function Link1degSliderValueChanged(app, event)
            forward(app);
        end

        % Value changed function: Link2degSlider
        function Link2degSliderValueChanged(app, event)
            forward(app);
        end

        % Value changed function: Link3mmSlider
        function Link3mmSliderValueChanged(app, event)
            forward(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Link1degSliderLabel = uilabel(app.GridLayout);
            app.Link1degSliderLabel.Text = 'Link1 (deg)';
            app.Link2degSliderLabel = uilabel(app.GridLayout);
            app.Link2degSliderLabel.Text = 'Link2 (deg)';
            app.Link3mmSliderLabel = uilabel(app.GridLayout);
            app.Link3mmSliderLabel.Text = 'Link3 (mm)';
            app.XCoordinateSliderLabel = uilabel(app.GridLayout);
            app.XCoordinateSliderLabel.Text = 'X Coordinate';
            app.YCoordinateSliderLabel = uilabel(app.GridLayout);
            app.YCoordinateSliderLabel.Text = 'Y Coordinate';
            app.ZCoordinateSliderLabel = uilabel(app.GridLayout);
            app.ZCoordinateSliderLabel.Text = 'Z Coordinate';
            app.Link1degSlider = uislider(app.GridLayout);
            app.Link1degSlider.Limits = [-135 135];
            app.Link1degSlider.ValueChangedFcn = createCallbackFcn(app, @Link1degSliderValueChanged, true);
            app.Link2degSlider = uislider(app.GridLayout);
            app.Link2degSlider.Limits = [-135 135];
            app.Link2degSlider.ValueChangedFcn = createCallbackFcn(app, @Link2degSliderValueChanged, true);
            app.Link3mmSlider = uislider(app.GridLayout);
            app.Link3mmSlider.ValueChangedFcn = createCallbackFcn(app, @Link3mmSliderValueChanged, true);
            app.XCoordinateSlider = uislider(app.GridLayout);
            app.XCoordinateSlider.Limits = [-280 280];
            app.XCoordinateSlider.ValueChangedFcn = createCallbackFcn(app, @XCoordinateSliderValueChanged, true);
            app.XCoordinateSlider.Value = 280;
            app.YCoordinateSlider = uislider(app.GridLayout);
            app.YCoordinateSlider.Limits = [-280 280];
            app.YCoordinateSlider.ValueChangedFcn = createCallbackFcn(app, @YCoordinateSliderValueChanged, true);
            app.ZCoordinateSlider = uislider(app.GridLayout);
            app.ZCoordinateSlider.Limits = [22 122];
            app.ZCoordinateSlider.ValueChangedFcn = createCallbackFcn(app, @ZCoordinateSliderValueChanged, true);
            app.ZCoordinateSlider.Value = 122;
            app.InverseKinematicsLabel = uilabel(app.GridLayout);
            app.InverseKinematicsLabel.Text = 'Inverse Kinematics';
            app.ForwardKinematicsLabel = uilabel(app.GridLayout);
            app.ForwardKinematicsLabel.Text = 'Forward Kinematics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManipulatorApp_Sliders

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end